classdef Buffon_Needle_Simulation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TaskDropDownLabel              matlab.ui.control.Label
        TaskDropDown                   matlab.ui.control.DropDown
        Label                          matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        SimulateButton                 matlab.ui.control.Button
        NumberofplanksofwoodEditFieldLabel  matlab.ui.control.Label
        NumberofplanksofwoodEditField  matlab.ui.control.NumericEditField
        SidelengthofsquaresEditFieldLabel  matlab.ui.control.Label
        SidelengthofsquaresEditField   matlab.ui.control.NumericEditField
        RepresentintersectionswithDropDownLabel  matlab.ui.control.Label
        RepresentintersectionswithDropDown  matlab.ui.control.DropDown
        RepresentnonintersectionswithDropDownLabel  matlab.ui.control.Label
        RepresentnonintersectionswithDropDown  matlab.ui.control.DropDown
        HighlighttheselectedneedlewithDropDownLabel  matlab.ui.control.Label
        HighlighttheselectedneedlewithDropDown  matlab.ui.control.DropDown
        NumberofmostsimilarlyorientedneedlestohighlightEditFieldLabel  matlab.ui.control.Label
        NumberofmostsimilarlyorientedneedlestohighlightEditField  matlab.ui.control.NumericEditField
        HighlightsimilarlyorientedneedleswithDropDownLabel  matlab.ui.control.Label
        HighlightsimilarlyorientedneedleswithDropDown  matlab.ui.control.DropDown
        NumberofclosestneedlesraystohighlightEditFieldLabel  matlab.ui.control.Label
        NumberofclosestneedlesraystohighlightEditField  matlab.ui.control.NumericEditField
        HighlightclosestrayswithDropDownLabel  matlab.ui.control.Label
        HighlightclosestrayswithDropDown  matlab.ui.control.DropDown
        HighlightraygeneratingneedleswithDropDownLabel  matlab.ui.control.Label
        HighlightraygeneratingneedleswithDropDown  matlab.ui.control.DropDown
        NumberofsquaresLabel           matlab.ui.control.Label
        NumberofsquaresSlider          matlab.ui.control.Slider
        Label_3                        matlab.ui.control.Label
        ShuffleButton                  matlab.ui.control.Button
        FontsizeDropDownLabel          matlab.ui.control.Label
        FontsizeDropDown               matlab.ui.control.DropDown
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        N = 100 % Number of needles/squares/pentagons dropped
        d = 0.20 % Space between two cracks
        L = 0.10 % Length of the sides of dropped needles/squares/pentagons
        x % X coordinates of vertices
        y % Y coordinates of vertices
        x_center % X coordinates of the center points
        y_center % Y coordinates of the center points
        target % Used to trace the index of the selected needle
        simulated % A boolean to help trace whether the program is simulated after setting changes
        similar_num = 3 % Number of most similarly oriented needles to highlight
        angle % A matrix storing the angles of the randomly dropped needles/squares/pentagons
        similar_needles = zeros(3) % Used to store the highlighted most similarly oriented needles
        closest_num = 3 % Number of closest needles and rays to highlight
        closest_needles = zeros(3, 2) % Used to store the highlighted closest needles and rays
        selected % Used to store the highlighted selected needle
    end
    
    methods (Access = private)
        
        % Do a Monte Carlo simulation and update the axis
        function result = dropItems(app)
            % Task 1
            if(app.TaskDropDown.Value == "1")
                app.x_center = (1 - app.d) * rand(app.N, 1) + app.d / 2; % Random x value for the center of a square
                app.y_center = (1 - app.d) * rand(app.N, 1) + app.d / 2; % Random y value for the center of a square
                app.angle = pi * rand(app.N, 1) / 2 - pi / 4; % Random angle for a square
                
                % Coordinates of four vertices of a square
                x1 = app.x_center + app.L/sqrt(2) * cos(app.angle);
                x2 = app.x_center + app.L/sqrt(2) * sin(app.angle);
                x3 = app.x_center - app.L/sqrt(2) * cos(app.angle);
                x4 = app.x_center - app.L/sqrt(2) * sin(app.angle);
                y1 = app.y_center + app.L/sqrt(2) * sin(app.angle);
                y2 = app.y_center - app.L/sqrt(2) * cos(app.angle);
                y3 = app.y_center - app.L/sqrt(2) * sin(app.angle);
                y4 = app.y_center + app.L/sqrt(2) * cos(app.angle);
                app.x = [x1 x2 x3 x4 x1]; % Store the X coordinates as a vector
                app.y = [y1 y2 y3 y4 y1]; % Store the Y coordinates as a vector
                
                counter = 0; % Count the number of squares intersecting with the cracks
                drawCracks(app);% Draw cracks
                
                % Draw squares and count the number of squares intersecting with the cracks
                for i = 1 : app.N % Iterate through all squares
                    colour = getcolour(app, app.RepresentnonintersectionswithDropDown.Value); % Get the colour for non-intersections
                    
                    for n = 1 : (1 / app.d - 1) % Iterate through all cracks
                        
                        if((x1(i) >= n * app.d) && (x3(i) < n * app.d))
                            counter = counter + 1; % Number of intersections increase by one
                            colour = getcolour(app, app.RepresentintersectionswithDropDown.Value); % Get the colour for intersections
                            break % Break if an intersection is found
                        end
                        
                    end
                    
                    plot(app.UIAxes, app.x(i, :), app.y(i, :), colour); % Plot the sqaures on the axis
                end
                
                hold(app.UIAxes, 'off'); % Hold the plot off
                result = 4 * app.L / app.d / (counter / app.N); % Return the estimated pi value
                
            % Task 2
            elseif(app.TaskDropDown.Value == "2")
                app.x_center = (1 - app.d) * rand(app.N, 1) + app.d / 2; % Random x value for the center of a square
                app.y_center = (1 - app.d) * rand(app.N, 1) + app.d / 2; % Random y value for the center of a square
                app.angle = pi * rand(app.N, 1) / 2 - pi / 4; % Random angle for a square
                
                % Coordinates of four vertices of a square
                x1 = app.x_center + app.L/sqrt(2) * cos(app.angle);
                x2 = app.x_center + app.L/sqrt(2) * sin(app.angle);
                x3 = app.x_center - app.L/sqrt(2) * cos(app.angle);
                x4 = app.x_center - app.L/sqrt(2) * sin(app.angle);
                y1 = app.y_center + app.L/sqrt(2) * sin(app.angle);
                y2 = app.y_center - app.L/sqrt(2) * cos(app.angle);
                y3 = app.y_center - app.L/sqrt(2) * sin(app.angle);
                y4 = app.y_center + app.L/sqrt(2) * cos(app.angle);
                app.x = [x1 x2 x3 x4 x1]; % Store the X coordinates in a vector
                app.y = [y1 y2 y3 y4 y1]; % Store the Y coordinates in a vector
                
                counter1 = 0; % Count the number of squares intersecting with the cracks
                counter2 = 0; % Count the number of squares intersecting with cracks with two non-consecutive sides
                drawCracks(app); % Draw cracks
                
                % Draw squares and count both the number of squares intersecting with the cracks
                % and the number of squares intersecting with the cracks with two opposite sides
                for i = 1 : app.N % Iterate through all squares
                    colour = getcolour(app, app.RepresentnonintersectionswithDropDown.Value); % Get the colour fot non-intersections
                    
                    for n = 1 : (1 / app.d - 1) % Iterate through all cracks
                        
                        if((x1(i) >= n * app.d) && (x3(i) < n * app.d))
                            counter1 = counter1 + 1; % Number of intersections increase by one
                            colour = getcolour(app, app.RepresentintersectionswithDropDown.Value); % Get the colour for intersections
                            
                            if(x2(i) < x4(i))
                                
                                if((x2(i) <= n * app.d) && (x4(i) > n* app.d))
                                    counter2 = counter2 + 1; % Number of squares intersecting a crack with its two opposite sides increase by one
                                end
                                
                            else
                                
                                if((x2(i) >= n * app.d) && (x4(i) < n* app.d))
                                    counter2 = counter2 + 1; % Number of squares intersecting a crack with its two opposite sides increase by one
                                end
                                
                            end
                            break % Break if an intersection is found
                            
                        end
                        
                    end
                    
                    plot(app.UIAxes, app.x(i, :), app.y(i, :), colour); % Plot the squares on the axis
                end
                
                hold(app.UIAxes, 'off'); % Hold the plot off
                result = 2 - (1 - (counter2 / counter1)); % Return the estimated value of sqrt(2)
                
            % Task 3
            elseif(app.TaskDropDown.Value == "3")
                app.x_center = (1 - app.d) * rand(app.N, 1) + app.d / 2; % Random x value for the center of a needle
                app.y_center = (1 - app.d) * rand(app.N, 1) + app.d / 2; % Random y value for the center of a needle
                app.angle = pi * rand(app.N, 1) - pi / 2; % Random angle of a needle
                
                % Coordinates of two vertices of a needle
                x1 = app.x_center + app.L / 2 * cos(app.angle);
                x2 = app.x_center - app.L / 2 * cos(app.angle);
                y1 = app.y_center + app.L / 2 * sin(app.angle);
                y2 = app.y_center - app.L / 2 * sin(app.angle);
                app.x = [x1 x2]; % Store the X coordinates in a vector
                app.y = [y1 y2]; % Store the Y coordinates in a vector
                
                counter = 0; % Count the number of needles intersecting with the cracks
                drawTiles(app); % Draw square tiles on the axis
                
                % Draw needles and count the number of needles intersecting with the tiles
                for i = 1 : app.N % Iterate through all needles
                    colour = getcolour(app, app.RepresentnonintersectionswithDropDown.Value); % Get the colour for non-intersections
                    
                    for n = 1 : (1 / app.d - 1) % Iterate through all cracks
                        
                        if(((x1(i) >= n * app.d) && (x2(i) < n * app.d)) || ...
                                ((y1(i) >= n * app.d) && (y2(i) < n * app.d)) || ...
                                ((y1(i) <= n * app.d) && (y2(i) > n * app.d)))
                        counter = counter + 1; % Number of intersections increase by one
                        colour = getcolour(app, app.RepresentintersectionswithDropDown.Value); % Get the colour for intersections
                        break % Break if an intersection is found
                        end
                        
                    end
                    
                    plot(app.UIAxes, app.x(i, :), app.y(i, :), colour); % Plot the needles on the axis
                end
                
                hold(app.UIAxes, 'off'); % Hold the plot off
                result = (4 * app.L * app.d - (app.L ^ 2)) / (app.d ^ 2) / (counter / app.N); % Return the estimated value of pi
                
            % Estimation of the Golden Ratio
            elseif(app.TaskDropDown.Value == "Golden Ratio")
                app.x_center = (1 - app.d) * rand(app.N, 1) + app.d / 2; % Random x value for the center of a pentagon
                app.y_center = (1 - app.d) * rand(app.N, 1) + app.d / 2; % Random y value for the center of a pentagon
                app.angle = pi * rand(app.N, 1) / 2.5 - pi / 5; % Random angle for a pentagon
                k = app.L / 2 / sin(0.2 * pi); % The distance from a vertice to the center of a pentagon
                
                % Coordinates of five vertices of the pentagon
                x1 = app.x_center - k * cos(app.angle);
                x2 = app.x_center - k * cos(0.4 * pi - app.angle);
                x3 = app.x_center + k * cos(0.2 * pi + app.angle);
                x4 = app.x_center + k * cos(0.2 * pi - app.angle);
                x5 = app.x_center + k * sin(app.angle - 0.1 * pi);
                y1 = app.y_center + k * sin(app.angle);
                y2 = app.y_center - k * sin(0.4 * pi - app.angle);
                y3 = app.y_center - k * sin(0.2 * pi + app.angle);
                y4 = app.y_center + k * sin(0.2 * pi - app.angle);
                y5 = app.y_center + k * cos(app.angle - 0.1 * pi);
                app.x = [x1 x2 x3 x4 x5 x1]; % Store the X coordinates in a vector
                app.y = [y1 y2 y3 y4 y5 y1]; % Store the Y coordinates in a vector
                
                counter1 = 0; % Count the number of pentagons intersecting with the cracks
                counter2 = 0; % Count the number of pentagons intersecting with cracks with two non-consecutive sides
                drawCracks(app); % Draw cracks
                
                % Draw pentagons and count both the number of pentagons intersecting with the cracks
                % and the number of pentagons intersecting with the cracks with two non-consecutive sides
                for i = 1 : app.N % Iterate through all pentagons
                    colour = getcolour(app, app.RepresentnonintersectionswithDropDown.Value); % Get the colour for non-intersections
                    
                    for n = 1 : (1 / app.d - 1) % Iterate through all cracks
                        
                        if(app.angle(i) < 0) % Case 1: angle is negative
                            
                            if((x1(i) <= n * app.d) && (x3(i) > n * app.d))
                                counter1 = counter1 + 1; % Number of intersections increase by one
                                colour = getcolour(app, app.RepresentintersectionswithDropDown.Value); % Get the colour for intersections
                                
                                if((x4(i) >= n * app.d) && (x5(i) < n * app.d))
                                    counter2 = counter2 + 1; % Number of pentagons intersecting a crack with two non-consecutive sides increase by one
                                end
                                
                                break % Break if an intersection is found
                            end
                        
                        else % Case 2: angle is non-negative
                            
                            if((x1(i) <= n * app.d) && (x4(i) > n * app.d))
                                counter1 = counter1 + 1; % Number of intersections increase by one
                                colour = getcolour(app, app.RepresentintersectionswithDropDown.Value); % Get the colour for intersections
                                
                                if((x3(i) >= n * app.d) && (x2(i) < n * app.d))
                                    counter2 = counter2 + 1; % Number of pentagons intersecting a crack with two non-consecutive sides increase by one
                                end
                                
                                break % Break if an intersection is found
                            end
                            
                        end
                        
                    end
                    
                    plot(app.UIAxes, app.x(i, :), app.y(i, :), colour); % Plot all the pentagons on the axis
                end
                
                hold(app.UIAxes, 'off'); % Hold the plot off
                result = 2 - (1 - (counter2 / counter1)); % Return the estimated value of the Golden Ratio
            end
        end
        
        % Draw cracks on the axis (Used in tasks other than 3)
        function drawCracks(app)
            for i = 1 : (1 / app.d - 1)
                plot(app.UIAxes, [app.d * i app.d * i], [0 1], 'k'); % Draw cracks
                
                if(i == 1)
                    hold(app.UIAxes, 'on'); % Hold the plot on
                end
                
            end
        end
        
        % Prompt a Monte Carlo simulation and update the result
        function simulate(app)
            if(app.TaskDropDown.Value == "1")
                app.Label.Text = string(dropItems(app)); % Prompt a simulation
                app.Label_2.Text = append('Estimation of ', char(960), ' is:'); % change the text clarifying the output
            
            elseif(app.TaskDropDown.Value == "2")
                app.Label.Text = string(dropItems(app)); % Prompt a simulation
                app.Label_2.Text = append('Estimation of ', char(8730), '2 is:'); % change the text clarifying the output
            
            elseif(app.TaskDropDown.Value == "3")
                app.Label.Text = string(dropItems(app)); % Prompt a simulation
                app.Label_2.Text = append('Estimation of ', char(960), ' is:'); % change the text clarifying the output
            
            else
                app.Label.Text = string(dropItems(app)); % Prompt a simulation
                app.Label_2.Text = append('Estimation of ', char(981), ' is:'); % change the text clarifying the output
            end
        end
        
        % Draw square tiles on the axis (Used in task 3)
        function drawTiles(app)
            for i = 1 : (1 / app.d - 1)
                plot(app.UIAxes, [app.d * i app.d * i], [0 1], 'k'); % Draw vertical cracks
                
                if(i == 1)
                    hold(app.UIAxes, 'on'); % Hold the plot on
                end
                
                plot(app.UIAxes, [0 1], [app.d * i app.d * i], 'k'); % Draw horizontal cracks
            end
        end
        
        % Clear previous highlighted most similarly oriented needles 
        % (Used in task 3)
        function clear_similar(app)
            hold(app.UIAxes, 'on'); % Hold the plot on
            
            for i = 1 : app.similar_num % Iterate through all highlighted needles
                
                if(app.similar_needles(i) ~= 0) % Check if there are any highlighted elements to delete
                    delete(app.similar_needles(i)); % Delete the highlight layer
                end
                
            end
            
            hold(app.UIAxes, 'off'); % Hold the plot off
        end
        
        % Clear previous highlighted closest needles and generated rays
        % (Used in task 3)
        function clear_closest(app)
            hold(app.UIAxes, 'on'); % Hold the plot on
            
            for i = 1 : app.closest_num % Iterate through all highlighted needles and generated rays
                
                if(app.closest_needles(i, 1) ~= 0) % Check if there are any highlighted elements to delete
                    delete(app.closest_needles(i, 1)); % Delete the highlight layer
                    delete(app.closest_needles(i, 2)); % Delete the generated rays
                end
                
            end
            
            hold(app.UIAxes, 'off'); % Hold the plot off
        end
        
        % Helper function to transform names of colours into abbreviations
        function result = getcolour(~, colour)
            % Yellow, Magenta, Cyan, Red, Green, Blue, White, Black
            switch(colour)
                case 'Red'
                    result = 'r';
                case 'Blue'
                    result = 'b';
                case 'Green'
                    result = 'g';
                case 'Yellow'
                    result = 'y';
                case 'Magenta'
                    result = 'm';
                case 'Cyan'
                    result = 'c';
                case 'White'
                    result = 'w';
                case 'Black'
                    result = 'k';
            end
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            simulate(app); % Do a Monte Carlo simulation
            app.simulated = true; % Set the simulated mark as true
            app.target = 0; % Initialize target as 0 (Used in task 3)
            app.similar_needles = zeros(app.similar_num); % Initialize similar_needles (Used in task 3)
            app.closest_needles = zeros(app.closest_num, 2); % Initialize closest_needles (Used in task 3)
        end

        % Value changed function: TaskDropDown
        function TaskDropDownValueChanged(app, event)
            value = app.TaskDropDown.Value;
            app.simulated = false; % Set the simulated mark as false
            
            % Enable or unenable buttons, dropdwons and editfields, 
            % and set the text of labels
            if(value == "1" || value == "2") % Task 1 & 2 response
                app.NumberofplanksofwoodEditFieldLabel.Text = 'Number of planks of wood';
                app.NumberofsquaresLabel.Text = {'Number '; 'of squares:'};
                app.SidelengthofsquaresEditFieldLabel.Text = 'Side length of squares';
                app.HighlightclosestrayswithDropDown.Enable = 'off';
                app.HighlightclosestrayswithDropDownLabel.Enable = 'off';
                app.HighlightraygeneratingneedleswithDropDown.Enable = 'off';
                app.HighlightraygeneratingneedleswithDropDownLabel.Enable = 'off';
                app.HighlightsimilarlyorientedneedleswithDropDown.Enable = 'off';
                app.HighlightsimilarlyorientedneedleswithDropDownLabel.Enable = 'off';
                app.HighlighttheselectedneedlewithDropDown.Enable = 'off';
                app.HighlighttheselectedneedlewithDropDownLabel.Enable = 'off';
                app.NumberofclosestneedlesraystohighlightEditField.Enable = 'off';
                app.NumberofclosestneedlesraystohighlightEditFieldLabel.Enable = 'off';
                app.NumberofmostsimilarlyorientedneedlestohighlightEditField.Enable = 'off';
                app.NumberofmostsimilarlyorientedneedlestohighlightEditFieldLabel.Enable = 'off';
                
            elseif(value == "3") % Task 3 response
                app.NumberofplanksofwoodEditFieldLabel.Text = 'Number of rows(columns) of tiles';
                app.NumberofsquaresLabel.Text = {'Number '; 'of needles:'};
                app.SidelengthofsquaresEditFieldLabel.Text = 'Length of needles';
                app.HighlightclosestrayswithDropDown.Enable = 'on';
                app.HighlightclosestrayswithDropDownLabel.Enable = 'on';
                app.HighlightraygeneratingneedleswithDropDown.Enable = 'on';
                app.HighlightraygeneratingneedleswithDropDownLabel.Enable = 'on';
                app.HighlightsimilarlyorientedneedleswithDropDown.Enable = 'on';
                app.HighlightsimilarlyorientedneedleswithDropDownLabel.Enable = 'on';
                app.HighlighttheselectedneedlewithDropDown.Enable = 'on';
                app.HighlighttheselectedneedlewithDropDownLabel.Enable = 'on';
                app.NumberofclosestneedlesraystohighlightEditField.Enable = 'on';
                app.NumberofclosestneedlesraystohighlightEditFieldLabel.Enable = 'on';
                app.NumberofmostsimilarlyorientedneedlestohighlightEditField.Enable = 'on';
                app.NumberofmostsimilarlyorientedneedlestohighlightEditFieldLabel.Enable = 'on';
                
            elseif(value == "Golden Ratio") % Task Golden Ratio response
                app.NumberofplanksofwoodEditFieldLabel.Text = 'Number of planks of wood';
                app.NumberofsquaresLabel.Text = {'Number '; 'of pentagons:'};
                app.SidelengthofsquaresEditFieldLabel.Text = 'Side length of pentagons';
                app.HighlightclosestrayswithDropDown.Enable = 'off';
                app.HighlightclosestrayswithDropDownLabel.Enable = 'off';
                app.HighlightraygeneratingneedleswithDropDown.Enable = 'off';
                app.HighlightraygeneratingneedleswithDropDownLabel.Enable = 'off';
                app.HighlightsimilarlyorientedneedleswithDropDown.Enable = 'off';
                app.HighlightsimilarlyorientedneedleswithDropDownLabel.Enable = 'off';
                app.HighlighttheselectedneedlewithDropDown.Enable = 'off';
                app.HighlighttheselectedneedlewithDropDownLabel.Enable = 'off';
                app.NumberofclosestneedlesraystohighlightEditField.Enable = 'off';
                app.NumberofclosestneedlesraystohighlightEditFieldLabel.Enable = 'off';
                app.NumberofmostsimilarlyorientedneedlestohighlightEditField.Enable = 'off';
                app.NumberofmostsimilarlyorientedneedlestohighlightEditFieldLabel.Enable = 'off';
            end
            
            if(app.TaskDropDown.Value == "1" || app.TaskDropDown.Value == "2")
                
                % Check if the side length is appropriate
                if((app.d / sqrt(2)) < app.L)
                    app.L = app.d / sqrt(2);
                    app.SidelengthofsquaresEditField.Value = app.L;
                end
                
            elseif(app.TaskDropDown.Value == "Golden Ratio")
                
                % Check if the side length is appropriate
                if(app.d < (2 * app.L * sin(0.3 * pi)))
                    app.L = app.d / 2 / sin(0.3 * pi);
                    app.SidelengthofsquaresEditField.Value = app.L;
                end
                
            end
            
        end

        % Value changed function: NumberofplanksofwoodEditField
        function NumberofplanksofwoodEditFieldValueChanged(app, event)
            value = app.NumberofplanksofwoodEditField.Value;
            app.simulated = false; % Set the simulated mark as false
            
            % Check if the number of planks of wood or rows/columns of square tiles is in the range, 
            % then calculated the distance between two cracks and pass it to app.d
            if(value < 2)
                app.d = 0.5;
                app.NumberofplanksofwoodEditField.Value = 2;
            else
                app.d = 1 / value;
            end
            
            % Under circumstances in task 1 & 2, check if the side length is appropriate; 
            % if not, find the largest possible appropriate side length and save it to app.L
            if(app.TaskDropDown.Value == "1" || app.TaskDropDown.Value == "2")
                
                if((app.d / sqrt(2)) < app.L)
                    app.L = app.d / sqrt(2);
                    app.SidelengthofsquaresEditField.Value = app.L;
                end
            
            % Under circumstances in task 3, check if the side length is appropriate; 
            % if not, find the largest possible appropriate side length and save it to app.L
            elseif(app.TaskDropDown.Value == "3")
                
                if(app.d < app.L)
                    app.L = app.d;
                    app.SidelengthofsquaresEditField.Value = app.L;
                end
            
            % Under circumstances in task Golden Ratio, check if the side length is appropriate; 
            % if not, find the largest possible appropriate side length and save it to app.L
            elseif(app.TaskDropDown.Value == "Golden Ratio")
                
                if(app.d < (2 * app.L * sin(0.3 * pi)))
                    app.L = app.d / 2 / sin(0.3 * pi);
                    app.SidelengthofsquaresEditField.Value = app.L;
                end
                
            end
            
        end

        % Value changed function: SidelengthofsquaresEditField
        function SidelengthofsquaresEditFieldValueChanged(app, event)
            value = app.SidelengthofsquaresEditField.Value;
            app.simulated = false; % Set the simulated mark as false
            
            % For task 1 & 2, check if the number for the side length is in the range, 
            % then check whether the number of planks of wood is appropriate
            if(app.TaskDropDown.Value == "1" || app.TaskDropDown.Value == "2")
                
                if(value > (0.5 / sqrt(2)) || value <= 0)
                    app.L = 0.5 / sqrt(2);
                    app.SidelengthofsquaresEditField.Value = 0.5 / sqrt(2);
                else
                    app.L = value;
                end
                
                if(app.L > (app.d / sqrt(2)))
                    app.NumberofplanksofwoodEditField.Value = floor(1 / app.L / sqrt(2));
                    app.d = 1 / app.NumberofplanksofwoodEditField.Value;
                end
                
            % For task 3, check if the number for the side length is in the range, 
            % then check whether the number of rows/columns of square tiles is appropriate
            elseif(app.TaskDropDown.Value == "3")
                
                if(value > 0.5)
                    app.L = 0.5;
                    app.SidelengthofsquaresEditField.Value = 0.5;
                else
                    app.L = value;
                end
                
                if(app.L > app.d)
                    app.NumberofplanksofwoodEditField.Value = floor(1 / app.L);
                    app.d = 1 / app.NumberofplanksofwoodEditField.Value;
                end
                
            % For task Golden Ratio, check if the number for the side length is in the range, 
            % then check whether the number of planks of wood is appropriate
            elseif(app.TaskDropDown.Value == "Golden Ratio")
                
                if(value > 0.5 / 2 / sin(0.3 * pi))
                    app.L = 0.5 / 2 / sin(0.3 * pi);
                else
                    app.L = value;
                end
                
                if(app.L > (app.d / 2 / sin(0.3 * pi)))
                    app.NumberofplanksofwoodEditField.Value = floor(1 / 2 / app.L / sin(0.3 * pi));
                    app.d = 1 / app.NumberofplanksofwoodEditField.Value;
                end
            end
            
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            
            % First check if it is now implementing task 3;
            % if yes, clear previous highlighted needles and generated rays
            if(app.TaskDropDown.Value == "3" && app.simulated)
                coords = app.UIAxes.CurrentPoint; % Get the coordinates of the mouse as a click happens on the UIFigure
                
                % Then, check whether the click happens inside the axis;
                % if yes, go ahead and implement three requirements in task 4 seperately
                if(coords(1, 1) <= 1 && coords(1, 1) >= 0 && coords(1, 2) <= 1 && coords(1, 2) >= 0)
                    hold(app.UIAxes, 'on'); % Hold the plot on
                    
                    % Clear previous highlights
                    if(app.target ~= 0) % Check if the highlighted selected needle isn't deleted
                        delete(app.selected);
                    end
                    
                    clear_similar(app); % Clear the highlighted most similarly oriented needles
                    clear_closest(app); % Clear the highlighted closest needles and generated rays
                    
                    % Task 4.1, highlight the selected needle
                    hold(app.UIAxes, 'on'); % Hold the plot on
                    distance = 2; % Set the inital distance as a number large enough
                    
                    for i = 1 : app.N % Iterate through all needles
                        
                        % Find the closest needle to the point the click has happened
                        if(((app.x_center(i) - coords(1, 1)) ^ 2 + (app.y_center(i) - coords(1, 2)) ^ 2) < distance)
                            app.target = i;
                            distance = (app.x_center(i) - coords(1, 1)) ^ 2 + (app.y_center(i) - coords(1, 2)) ^ 2;
                        end
                        
                    end
                    
                    app.selected = plot(app.UIAxes, app.x(app.target, :), app.y(app.target, :), ...
                        getcolour(app, app.HighlighttheselectedneedlewithDropDown.Value)); % Plot and record the selected needle
                    
                    % Task 4.2
                    angle_copy = app.angle; % Get a copy of angles for future use
                    
                    for n = 1 : app.similar_num % Repeat the following operation until wanted number of needles are found
                        diff = 4; % Used to trace minimum difference between angles
                        temp = 0; % Used to trace the index of minimum-angle-difference needle
                        
                        for i = 1 : app.N % Iterate through all needles
                            
                            % Find the most closely oriented needle
                            if(i ~= app.target && abs(angle_copy(i) - angle_copy(app.target)) < diff)
                                diff = abs(angle_copy(i) - angle_copy(app.target));
                                temp = i;
                            end
                            
                        end
                        
                        angle_copy(temp) = 6; % Set the angle large enough
                        app.similar_needles(n) = plot(app.UIAxes, app.x(temp, :), app.y(temp, :), ...
                            getcolour(app, app.HighlightsimilarlyorientedneedleswithDropDown.Value)); % Plot and record the highlighted needle
                    end
                    
                    % Task 4.3
                    % Calculate distances of each other needle with the selected one
                    distance = zeros(app.N);
                    
                    for i = 1 : app.N % Iterate through all needles and find its special distance to the selected needle
                        
                        if(i == app.target)
                            distance(i) = 3;  % Set the distance of the selected needle to itself large enough
                        else
                            k = (app.y(i, 1) - app.y(i, 2)) / (app.x(i, 1) - app.x(i, 2)); % Slope of i-th needle
                            k_r = -1 / k; % negative reciprocal of the slope
                            
                            % Two temporary values for future compares and calculations
                            temp1 = (app.x(i, 1) * k - app.y(i, 1) - app.x(app.target, 1) * k + app.y(app.target, 1)) ^ 2;
                            temp2 = (app.x(i, 1) * k - app.y(i, 1) - app.x(app.target, 2) * k + app.y(app.target, 2)) ^ 2;
                            
                            % Find the smallest special distance and save it in a the distance vector
                            if(temp1 < temp2)
                                distance(i) = temp1 / ((k - k_r) ^ 2) + temp1 / ((1 + k ^ 2) ^ 2);
                            
                            else
                                distance(i) = temp2 / ((k - k_r) ^ 2) + temp2 / ((1 + k ^ 2) ^ 2);
                            end
                            
                        end
                        
                    end
                    
                    % Find the needles generating the closest rays
                    for n = 1 : app.closest_num % Repeat the following operation until wanted number of needles are found
                        dist = 3; % Set the inital value of distance large enough
                        temp = 0;
                        
                        for i = 1 : app.N % Iterate though all needles
                            
                            % Find the closest needle
                            if(distance(i) < dist)
                                dist = distance(i);
                                temp = i;
                            end
                            
                        end
                        
                        distance(temp) = 3; % Set the distance large enough
                        k = (app.y(temp, 1) - app.y(temp, 2)) / (app.x(temp, 1) - app.x(temp, 2)); % Get the slope of this specific needle 
                        % in order to find the ray
                        x_temp = []; % Initialize an empty vector to store X coordinates of the corresponding ray
                        y_temp = []; % Initialize an empty vector to store Y coordinates of the corresponding ray
                        
                        % Try the find the two vertices of the ray from four possible points
                        t = (1 - app.x(temp, 1)) * k + app.y(temp, 1);
                        
                        if(t <= 1 && t >= 0)
                            y_temp = [y_temp t];
                            x_temp = [x_temp 1];
                        end
                        
                        t = (-app.x(temp, 1)) * k + app.y(temp, 1);
                        
                        if(t <= 1 && t >= 0)
                            y_temp = [y_temp t];
                            x_temp = [x_temp 0];
                        end
                        
                        t = app.x(temp, 1) + (1 - app.y(temp, 1)) / k;
                        
                        if(t <= 1 && t >= 0)
                            x_temp = [x_temp t];
                            y_temp = [y_temp 1];
                        end
                        
                        t = app.x(temp, 1) + (-app.y(temp, 1)) / k;
                        
                        if(t <= 1 && t >= 0)
                            x_temp = [x_temp t];
                            y_temp = [y_temp 0];
                        end
                        
                        % Plot the ray and the ray-generating needle
                        app.closest_needles(n, 1) = plot(app.UIAxes, x_temp, y_temp, getcolour(app, app.HighlightclosestrayswithDropDown.Value));
                        app.closest_needles(n, 2) = plot(app.UIAxes, app.x(temp, :), app.y(temp, :), getcolour(app, ...
                            app.HighlightraygeneratingneedleswithDropDown.Value));
                    end
                    
                    hold(app.UIAxes, 'off'); % Hold the plot off
                end
            end
            
        end

        % Value changed function: NumberofsquaresSlider
        function NumberofsquaresSliderValueChanged(app, event)
            app.N = round(app.NumberofsquaresSlider.Value);
            app.simulated = false; % Set the simulated mark as false
            app.Label_3.Text = string(app.N); % Update the text which shows the value of the slider
            
            % Check if the number wanted for most similarly oriented needles is appropriate
            if(app.similar_num > app.N)
                clear_similar(app); % Clear previously highlight layer if the number wanted isn't appropriate
                app.similar_num = app.N - 1; % Set the number to the upper limit
                app.NumberofmostsimilarlyorientedneedlestohighlightEditField.Value = app.similar_num; % Update the label
                app.similar_needles = zeros(app.similar_num); % Initialize the similar_needles vector
            end
            
            % Check if the number wanted for closest needles is appropriate
            if(app.closest_num > app.N)
                clear_closest(app); % Clear previously highlight layer and rays if the number wanted isn't appropriate
                app.closest_num = app.N - 1; % Set the number to the upper limit
                app.NumberofclosestneedlesraystohighlightEditField.Value = app.closest_num; % Update the label
                app.closest_needles = zeros(app.closest_num, 2); % Initialize the closest_needles vector
            end
            
        end

        % Value changed function: 
        % NumberofmostsimilarlyorientedneedlestohighlightEditField
        function NumberofmostsimilarlyorientedneedlestohighlightEditFieldValueChanged(app, event)
            value = app.NumberofmostsimilarlyorientedneedlestohighlightEditField.Value;
            clear_similar(app); % Clear previous highlighted needles
            
            % Check if the value is appropriate, then pass it to similar_num
            if(value < 0)
                app.similar_num = 0;
                app.NumberofmostsimilarlyorientedneedlestohighlightEditField.Value = 0;
                
            elseif(value >= app.N)
                app.similar_num = app.N - 1;
                app.NumberofmostsimilarlyorientedneedlestohighlightEditField.Value = app.similar_num;
                
            else
                app.similar_num = value;
            end
            
            app.similar_needles = zeros(app.similar_num); % Initialize similar_needles vector
            
        end

        % Value changed function: 
        % NumberofclosestneedlesraystohighlightEditField
        function NumberofclosestneedlesraystohighlightEditFieldValueChanged(app, event)
            value = app.NumberofclosestneedlesraystohighlightEditField.Value;
            clear_closest(app); % Clear previous highlighted needles and rays
            
            % Check if the value is appropriate, then pass it to closest_num
            if(value < 0)
                app.closest_num = 0;
                app.NumberofclosestneedlesraystohighlightEditField.Value = 0;
                
            elseif(value >= app.N)
                app.closest_num = app.N - 1;
                app.NumberofclosestneedlesraystohighlightEditField.Value = app.closest_num;
                
            else
                app.closest_num = value;
            end
            
            app.closest_needles = zeros(app.closest_num, 2); % Initialize closest_needles vector
        end

        % Value changed function: FontsizeDropDown
        function FontsizeDropDownValueChanged(app, event)
            value = app.FontsizeDropDown.Value;
            
            % Change the font size of all labels in the GUI
            app.TaskDropDownLabel.FontSize = value;
            app.TaskDropDown.FontSize = value;
            app.NumberofplanksofwoodEditFieldLabel.FontSize = value;
            app.NumberofplanksofwoodEditField.FontSize = value;
            app.SidelengthofsquaresEditFieldLabel.FontSize = value;
            app.SidelengthofsquaresEditField.FontSize = value;
            app.RepresentintersectionswithDropDownLabel.FontSize = value;
            app.RepresentintersectionswithDropDown.FontSize = value;
            app.RepresentnonintersectionswithDropDownLabel.FontSize = value;
            app.RepresentnonintersectionswithDropDown.FontSize = value;
            app.FontsizeDropDownLabel.FontSize = value;
            app.FontsizeDropDown.FontSize = value;
            app.ShuffleButton.FontSize = value;
            app.HighlighttheselectedneedlewithDropDownLabel.FontSize = value;
            app.HighlighttheselectedneedlewithDropDown.FontSize = value;
            app.NumberofmostsimilarlyorientedneedlestohighlightEditFieldLabel.FontSize = value;
            app.NumberofmostsimilarlyorientedneedlestohighlightEditField.FontSize = value;
            app.HighlightsimilarlyorientedneedleswithDropDownLabel.FontSize = value;
            app.HighlightsimilarlyorientedneedleswithDropDown.FontSize = value;
            app.NumberofclosestneedlesraystohighlightEditFieldLabel.FontSize = value;
            app.NumberofclosestneedlesraystohighlightEditField.FontSize = value;
            app.HighlightclosestrayswithDropDownLabel.FontSize = value;
            app.HighlightclosestrayswithDropDown.FontSize = value;
            app.HighlightraygeneratingneedleswithDropDownLabel.FontSize = value;
            app.HighlightraygeneratingneedleswithDropDown.FontSize = value;
            app.NumberofsquaresLabel.FontSize = value;
            app.NumberofsquaresSlider.FontSize = value;
            app.Label.FontSize = value;
            app.Label_2.FontSize = value;
            app.Label_3.FontSize = value;
            app.UIAxes.Title.FontSize = value;
            app.SimulateButton.FontSize = value;
        end

        % Button pushed function: ShuffleButton
        function ShuffleButtonPushed(app, event)
            app.simulated = false; % Set the simulated mark as false
            temp = floor(99 * rand(1)) + 2; % Get a random number of planks of wood or columns/rows of square tiles
            app.d = 1 / temp; % Calculate and pass the distance between two cracks to app.d
            app.NumberofplanksofwoodEditField.Value = temp; % Update the label
            
            % For different tasks, find a random value for the side length
            if(app.TaskDropDown.Value == "1" || app.TaskDropDown.Value == "2")
                app.L = (app.d / sqrt(2)) * (1 - rand(1));
                
            elseif(app.TaskDropDown.Value == "3")
                app.L = app.d * (1 - rand(1));
                
            elseif(app.TaskDropDown.Value == "Golden Ratio")
                app.L = (app.d / 2 / sin(0.3 * pi)) * (1 - rand(1));
            end
            
            app.SidelengthofsquaresEditField.Value = app.L; % Update the label
            
            % Get a random number of squares/needles/pentagons and update the label and slider value
            app.N = floor(4996 * rand(1)) + 5;
            app.NumberofsquaresSlider.Value = app.N;
            app.Label_3.Text = string(app.N);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.TaskDropDownLabel = uilabel(app.UIFigure);
            app.TaskDropDownLabel.Text = 'Task';
            app.TaskDropDown = uidropdown(app.UIFigure);
            app.TaskDropDown.Items = {'1', '2', '3', 'Golden Ratio'};
            app.TaskDropDown.ValueChangedFcn = createCallbackFcn(app, @TaskDropDownValueChanged, true);
            app.TaskDropDown.Value = '1';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.NumberofplanksofwoodEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofplanksofwoodEditFieldLabel.Text = 'Number of planks of wood';
            app.NumberofplanksofwoodEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofplanksofwoodEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofplanksofwoodEditFieldValueChanged, true);
            app.NumberofplanksofwoodEditField.Value = 5;
            app.SidelengthofsquaresEditFieldLabel = uilabel(app.UIFigure);
            app.SidelengthofsquaresEditFieldLabel.Text = 'Side length of squares';
            app.SidelengthofsquaresEditField = uieditfield(app.UIFigure, 'numeric');
            app.SidelengthofsquaresEditField.ValueChangedFcn = createCallbackFcn(app, @SidelengthofsquaresEditFieldValueChanged, true);
            app.SidelengthofsquaresEditField.Value = 0.1;
            app.RepresentintersectionswithDropDownLabel = uilabel(app.UIFigure);
            app.RepresentintersectionswithDropDownLabel.Text = 'Represent intersections with';
            app.RepresentintersectionswithDropDown = uidropdown(app.UIFigure);
            app.RepresentintersectionswithDropDown.Items = {'Yellow', 'Magenta', 'Cyan', 'Red', 'Green', 'Blue', 'White', 'Black'};
            app.RepresentintersectionswithDropDown.Value = 'Red';
            app.RepresentnonintersectionswithDropDownLabel = uilabel(app.UIFigure);
            app.RepresentnonintersectionswithDropDownLabel.Text = 'Represent non-intersections with';
            app.RepresentnonintersectionswithDropDown = uidropdown(app.UIFigure);
            app.RepresentnonintersectionswithDropDown.Items = {'Yellow', 'Magenta', 'Cyan', 'Red', 'Green', 'Blue', 'White', 'Black'};
            app.RepresentnonintersectionswithDropDown.Value = 'Blue';
            app.HighlighttheselectedneedlewithDropDownLabel = uilabel(app.UIFigure);
            app.HighlighttheselectedneedlewithDropDownLabel.Enable = 'off';
            app.HighlighttheselectedneedlewithDropDownLabel.Text = 'Highlight the selected needle with';
            app.HighlighttheselectedneedlewithDropDown = uidropdown(app.UIFigure);
            app.HighlighttheselectedneedlewithDropDown.Items = {'Yellow', 'Magenta', 'Cyan', 'Red', 'Green', 'Blue', 'White', 'Black'};
            app.HighlighttheselectedneedlewithDropDown.Enable = 'off';
            app.HighlighttheselectedneedlewithDropDown.Value = 'Green';
            app.NumberofmostsimilarlyorientedneedlestohighlightEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofmostsimilarlyorientedneedlestohighlightEditFieldLabel.Enable = 'off';
            app.NumberofmostsimilarlyorientedneedlestohighlightEditFieldLabel.Text = 'Number of most similarly oriented needles to highlight';
            app.NumberofmostsimilarlyorientedneedlestohighlightEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofmostsimilarlyorientedneedlestohighlightEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofmostsimilarlyorientedneedlestohighlightEditFieldValueChanged, true);
            app.NumberofmostsimilarlyorientedneedlestohighlightEditField.Enable = 'off';
            app.NumberofmostsimilarlyorientedneedlestohighlightEditField.Value = 3;
            app.HighlightsimilarlyorientedneedleswithDropDownLabel = uilabel(app.UIFigure);
            app.HighlightsimilarlyorientedneedleswithDropDownLabel.Enable = 'off';
            app.HighlightsimilarlyorientedneedleswithDropDownLabel.Text = 'Highlight similarly oriented needles with';
            app.HighlightsimilarlyorientedneedleswithDropDown = uidropdown(app.UIFigure);
            app.HighlightsimilarlyorientedneedleswithDropDown.Items = {'Yellow', 'Magenta', 'Cyan', 'Red', 'Green', 'Blue', 'White', 'Black'};
            app.HighlightsimilarlyorientedneedleswithDropDown.Enable = 'off';
            app.HighlightsimilarlyorientedneedleswithDropDown.Value = 'Cyan';
            app.NumberofclosestneedlesraystohighlightEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofclosestneedlesraystohighlightEditFieldLabel.Enable = 'off';
            app.NumberofclosestneedlesraystohighlightEditFieldLabel.Text = 'Number of closest needles/rays to highlight';
            app.NumberofclosestneedlesraystohighlightEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofclosestneedlesraystohighlightEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofclosestneedlesraystohighlightEditFieldValueChanged, true);
            app.NumberofclosestneedlesraystohighlightEditField.Enable = 'off';
            app.NumberofclosestneedlesraystohighlightEditField.Value = 3;
            app.HighlightclosestrayswithDropDownLabel = uilabel(app.UIFigure);
            app.HighlightclosestrayswithDropDownLabel.Enable = 'off';
            app.HighlightclosestrayswithDropDownLabel.Text = 'Highlight closest rays with';
            app.HighlightclosestrayswithDropDown = uidropdown(app.UIFigure);
            app.HighlightclosestrayswithDropDown.Items = {'Yellow', 'Magenta', 'Cyan', 'Red', 'Green', 'Blue', 'White', 'Black'};
            app.HighlightclosestrayswithDropDown.Enable = 'off';
            app.HighlightclosestrayswithDropDown.Value = 'Magenta';
            app.HighlightraygeneratingneedleswithDropDownLabel = uilabel(app.UIFigure);
            app.HighlightraygeneratingneedleswithDropDownLabel.Enable = 'off';
            app.HighlightraygeneratingneedleswithDropDownLabel.Text = 'Highlight ray-generating needles with';
            app.HighlightraygeneratingneedleswithDropDown = uidropdown(app.UIFigure);
            app.HighlightraygeneratingneedleswithDropDown.Items = {'Yellow', 'Magenta', 'Cyan', 'Red', 'Green', 'Blue', 'White', 'Black'};
            app.HighlightraygeneratingneedleswithDropDown.Enable = 'off';
            app.HighlightraygeneratingneedleswithDropDown.Value = 'Yellow';
            app.NumberofsquaresLabel = uilabel(app.UIFigure);
            app.NumberofsquaresLabel.Text = {'Number '; 'of squares:'};
            app.NumberofsquaresSlider = uislider(app.UIFigure);
            app.NumberofsquaresSlider.Limits = [5 5000];
            app.NumberofsquaresSlider.ValueChangedFcn = createCallbackFcn(app, @NumberofsquaresSliderValueChanged, true);
            app.NumberofsquaresSlider.Value = 100;
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '100';
            app.ShuffleButton = uibutton(app.UIFigure, 'push');
            app.ShuffleButton.ButtonPushedFcn = createCallbackFcn(app, @ShuffleButtonPushed, true);
            app.ShuffleButton.Text = 'Shuffle';
            app.FontsizeDropDownLabel = uilabel(app.UIFigure);
            app.FontsizeDropDownLabel.Text = 'Font size';
            app.FontsizeDropDown = uidropdown(app.UIFigure);
            app.FontsizeDropDown.Items = {'10', '11', '12', '13'};
            app.FontsizeDropDown.ItemsData = [10 11 12 13];
            app.FontsizeDropDown.ValueChangedFcn = createCallbackFcn(app, @FontsizeDropDownValueChanged, true);
            app.FontsizeDropDown.Value = 12;
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Floor')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [0 1];
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Buffon_Needle_Simulation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end